classdef signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ListBox                 matlab.ui.control.ListBox
        filenameEditField       matlab.ui.control.EditField
        filenameEditFieldLabel  matlab.ui.control.Label
        importButton            matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Items = {} % Description
    end
    
    methods (Access = private)
        
        function plotAll(app, path)
            figure(app.UIFigure);
            [data, Fs] = audioread(path);
            time = (0:size(data,1)-1)/Fs;
            plot(app.UIAxes, time, data);
            [y,Fs] = audioread(path);
            N = length(y);
            X_mags = abs(fft(y));
            bin_vals = [0 : N-1];
            fax_Hz = bin_vals*Fs/N;
            N_2 = ceil(N/2);
            plot(app.UIAxes2,fax_Hz(1:N_2), X_mags(1:N_2)); %Plotting the Magnitude Spectrum
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
          app.ListBox.Items = app.Items;
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [filename, path] = uigetfile('*.wav', '*.mp3'); %https://www.mathworks.com/matlabcentral/answers/466061-plot-a-sound-file-in-matlab-gui
            fullPath = strcat(path, filename);
            app.filenameEditField.Value=fullPath;
            app.plotAll(fullPath);
            app.Items = [app.Items, fullPath];
            set(app.ListBox, 'Items', app.Items);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.plotAll(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time(t)')
            ylabel(app.UIAxes, 'Data')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, '(f)')
            ylabel(app.UIAxes2, '|PF(f)|')
            zlabel(app.UIAxes2, 'Z')
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end